classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        enterButton       matlab.ui.control.Button
        PIDTunerLabel     matlab.ui.control.Label
        TsEditField       matlab.ui.control.NumericEditField
        TsEditFieldLabel  matlab.ui.control.Label
        DEditField        matlab.ui.control.NumericEditField
        DEditFieldLabel   matlab.ui.control.Label
        IEditField        matlab.ui.control.NumericEditField
        IEditFieldLabel   matlab.ui.control.Label
        PEditField        matlab.ui.control.NumericEditField
        PEditFieldLabel   matlab.ui.control.Label
        resetButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        P_term=0;
        I_term=0;
        D_term=0;
        Ts_term=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: enterButton
        function enterButtonPushed(app, event)
            assignin("base","ws_P",app.P_term);
            assignin("base","ws_I",app.I_term);
            assignin("base","ws_D",app.D_term);
            assignin("base","ws_Ts",app.Ts_term);
            output=test(app.P_term);
        end

        % Value changed function: TsEditField
        function TsEditFieldValueChanged(app, event)
            app.Ts_term = app.TsEditField.Value;
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            app.D_term = app.DEditField.Value;
            
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            app.I_term = app.IEditField.Value;
            
        end

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            app.P_term = app.PEditField.Value;
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.P_term=0;
            app.I_term=0;
            app.D_term=0;
            app.Ts_term=0;
            app.PEditField.Value=0;
            app.IEditField.Value=0;
            app.DEditField.Value=0;
            app.TsEditField.Value=0;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.TsEditFieldLabel = uilabel(app.UIFigure);
            app.TsEditFieldLabel.Text = 'Ts';
            app.TsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TsEditField.ValueChangedFcn = createCallbackFcn(app, @TsEditFieldValueChanged, true);
            app.PIDTunerLabel = uilabel(app.UIFigure);
            app.PIDTunerLabel.Text = 'PID Tuner';
            app.enterButton = uibutton(app.UIFigure, 'push');
            app.enterButton.ButtonPushedFcn = createCallbackFcn(app, @enterButtonPushed, true);
            app.enterButton.Text = 'enter ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end